classdef Graph_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
        ArtefactButton  matlab.ui.control.Button
        ClearButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        h1; % plot1
    end
    
    methods (Access = private)
        
        function LoadData(app)
            load(uigetfile,'trials');
            plot(app.UIAxes,trials.ch1{1,1});
            plot(app.UIAxes_2,trials.ch1{2,1});
        end
        function artgraph(app)
            a = ones(1,101)*50;
            b = -50:50;
            hold(app.UIAxes);
            app.h1 = plot(app.UIAxes,a,b);
        end
        function deletegraph(app)
            delete(app.h1)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          LoadData(app)
        end

        % Button pushed function: ArtefactButton
        function ArtefactButtonPushed(app, event)
        artgraph(app)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
        deletegraph(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Figure 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Figure 2')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.ArtefactButton = uibutton(app.UIFigure, 'push');
            app.ArtefactButton.ButtonPushedFcn = createCallbackFcn(app, @ArtefactButtonPushed, true);
            app.ArtefactButton.Text = 'Artefact';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Graph_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end